classdef scope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        stopButton             matlab.ui.control.Button
        startButton            matlab.ui.control.Button
        daqboardDropDown       matlab.ui.control.DropDown
        daqboardDropDownLabel  matlab.ui.control.Label
        TIMEKnob               matlab.ui.control.DiscreteKnob
        TIMEKnobLabel          matlab.ui.control.Label
        VOLTAGEKnob            matlab.ui.control.DiscreteKnob
        VOLTAGEKnobLabel       matlab.ui.control.Label
        OFFSETVLabel           matlab.ui.control.Label
        displayCheckBox_4      matlab.ui.control.CheckBox
        displayCheckBox_3      matlab.ui.control.CheckBox
        displayCheckBox_2      matlab.ui.control.CheckBox
        displayCheckBox_1      matlab.ui.control.CheckBox
        channel4Slider         matlab.ui.control.Slider
        channel4SliderLabel    matlab.ui.control.Label
        channel3Slider         matlab.ui.control.Slider
        channel3SliderLabel    matlab.ui.control.Label
        channel2Slider         matlab.ui.control.Slider
        channel2SliderLabel    matlab.ui.control.Label
        channel1Slider         matlab.ui.control.Slider
        channel1SliderLabel    matlab.ui.control.Label
        OscilloscopeLabel      matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DaqObj % daq object
        DeviceID % daq device ID
        DeviceInfo % daq device info
        NIBoards % list of National Instruments boards
        Offsets % voltage offsets for plotting different channels
        VoltageScale % voltage scale for plotting
        TimeScale % time scale for plotting
        ChannelNames % names of analog input channels available
        PlotHandles % handles to plotted traces
        PlottingCounter % counter to keep track of number of updates
        TotalCounts % total number of updates
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
        function [] = createdaqobj(app)
            
            app.DaqObj = daq("ni");
            app.DaqObj.Rate = 10000; % always sample at 10 kHz
            foo = app.DeviceInfo.Subsystems(1).ChannelNames;
            nChannels = min(4,numel(foo));
            app.ChannelNames = foo(1:nChannels);
            for ii = 1:4
                fStr = ['displayCheckBox_',num2str(ii)];
                if nChannels>=ii
                    app.(fStr).Enable = true;
                else
                    app.(fStr).Value = false;
                    app.(fStr).Enable = false;
                end
            end
            app.DaqObj.ScansAvailableFcnCount = 1000; % always refresh at 100 msec (1000 sample points)
            app.DaqObj.ScansAvailableFcn = @(src,evt) startacquiring(app);
            
        end
        
        function [] = startacquiring(app)
            
            if app.PlottingCounter > app.TotalCounts
                app.PlottingCounter = 1;
                drawnow limitrate
            end
            data = read(app.DaqObj,app.DaqObj.ScansAvailableFcnCount,"OutputFormat","Matrix");
            lengthData = length(data);
            for ii = 1:size(data,2)
                data1 = data(:,ii) + app.Offsets(ii);
                ydata = get(app.PlotHandles(ii),'YData');
                ydata(1 + (app.PlottingCounter-1)*lengthData:app.PlottingCounter*lengthData) = data1';
                set(app.PlotHandles(ii),'YData',ydata)
            end
            app.PlottingCounter = app.PlottingCounter + 1;
        end
    end
    
    methods (Access = public)
        
        function [] = pushStopButton(app)
            
            stopButtonPushed(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global DAQPARS
            
            if isfield(DAQPARS,'MainApp')     % stop the main data acquisition app if it is open
                MainApp = DAQPARS.MainApp;
                try
                    if MainApp.TestPulse    % close test pulse if running
                        pushTestButton(MainApp)
                    end
                    if MainApp.Recording    % stop recording
                        pushStartButton(MainApp)
                    end
                catch
                    % do nothing -- maybe the main GUI is not open
                end
            end
            
            daqreset    % stop any other data acquisition objects
            
            app.NIBoards = daqlist('ni');
            if isempty(app.NIBoards)
                app.daqboardDropDown.Items = 'none';
                app.DeviceID = 'none';
                warndlg('No National Instruments board found','Warning');
            else
                app.daqboardDropDown.Items = app.NIBoards.Model;
                try
                    fileName = [DAQPARS.daqFolder,'\parameters_and_gui\','hardwareConfiguration.mat'];
                    load(fileName,'daqBoardInfo')
                    app.daqboardDropDown.Value = daqBoardInfo.Model;
                catch
                    % the default is the first model detected
                end
                daqboardDropDownValueChanged(app)
            end

            workFolder = userpath;
            DAQPARS.daqFolder = [workFolder(1:end),'\patchclamp'];
            addpath(genpath(DAQPARS.daqFolder)) % add everything to the search path

            warning('off','MATLAB:subscripting:noSubscriptsSpecified');

            
        end

        % Value changed function: daqboardDropDown
        function daqboardDropDownValueChanged(app, event)
            
            selectedBoard = app.daqboardDropDown.Value;
            if ~strcmp(selectedBoard,'none')
                idx = find(strcmp(selectedBoard,app.daqboardDropDown.Items));
                app.DeviceID = app.NIBoards.DeviceID(idx);
                app.DeviceInfo = app.NIBoards.DeviceInfo(idx);
                createdaqobj(app)
            end
                
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            
            if ~isvalid(app.DaqObj)
                app.NIBoards = daqlist("ni");
                daqboardDropDownValueChanged(app)
            end
            
            if app.DaqObj.Running
                return
            end
            
            if strcmp(app.DeviceID,'none')
                warndlg('No National Instruments board found','Warning');
                return
            end
            
            stop(app.DaqObj)
            flush(app.DaqObj)
            
            % find and add channels
            channelsToDisplay = false(4,1);
            for jj = 1:4
                 fStr = ['displayCheckBox_',num2str(jj)];
                 channelsToDisplay(jj) = app.(fStr).Value;
            end
            if isempty(find(channelsToDisplay, 1))
                warndlg('Select a channel to display','Warning');
                return
            end
            if ~isempty(app.DaqObj.Channels)
                removechannel(app.DaqObj,1:length(app.DaqObj.Channels))
            end
            addinput(app.DaqObj,app.DeviceID,app.ChannelNames(channelsToDisplay),"Voltage")
            
            % set horizontal and vertical scales
            app.Offsets = zeros(1,4);
            for ii = 1:4
                fStr = ['channel',num2str(ii),'Slider'];
                app.Offsets(ii) = app.(fStr).Value;
            end
            app.Offsets = app.Offsets(channelsToDisplay);
            app.VoltageScale = str2double(strtok(app.VOLTAGEKnob.Value));
            app.TimeScale = str2double(strtok(app.TIMEKnob.Value));
            voltageMin = min(app.Offsets)-app.VoltageScale;
            voltageMax = max(app.Offsets)+app.VoltageScale;
            ylim(app.UIAxes,[voltageMin voltageMax])
            xlim(app.UIAxes,[0 app.TimeScale])
                 
            load('plottingColors.mat','colors')  % this is in the parameters_and_gui folder
            colors = colors(channelsToDisplay,:);
            N = app.TimeScale * app.DaqObj.Rate; % number of points for whole plot
            
            data = NaN(N,numel(find(channelsToDisplay))) + app.Offsets;
            dt = 1/app.DaqObj.Rate; % seconds between sample points
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            for kk = 1:numel(find(channelsToDisplay))
                app.PlotHandles(kk) = plot(app.UIAxes, (1:length(data))*dt, data(:,kk),'Color',colors(kk,:));
            end
           
            app.PlottingCounter = 1;
            app.TotalCounts = floor(app.TimeScale/0.1);
            start(app.DaqObj,"continuous")
            
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            
            stop(app.DaqObj)
            flush(app.DaqObj)
            
        end

        % Value changed function: TIMEKnob, VOLTAGEKnob, channel1Slider, 
        % ...and 7 other components
        function VOLTAGEKnobValueChanged(app, event)
            if app.DaqObj.Running
                app.stopButtonPushed;
                channelsToDisplay = false(4,1);
                for jj = 1:4
                    fStr = ['displayCheckBox_',num2str(jj)];
                    channelsToDisplay(jj) = app.(fStr).Value;
                end
                if isempty(find(channelsToDisplay, 1))
                    return
                end
                app.startButtonPushed;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.OscilloscopeLabel = uilabel(app.UIFigure);
            app.OscilloscopeLabel.Text = 'Oscilloscope';
            app.channel1SliderLabel = uilabel(app.UIFigure);
            app.channel1SliderLabel.Text = 'channel 1';
            app.channel1Slider = uislider(app.UIFigure);
            app.channel1Slider.Limits = [-10 10];
            app.channel1Slider.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.channel2SliderLabel = uilabel(app.UIFigure);
            app.channel2SliderLabel.Text = 'channel 2';
            app.channel2Slider = uislider(app.UIFigure);
            app.channel2Slider.Limits = [-10 10];
            app.channel2Slider.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.channel3SliderLabel = uilabel(app.UIFigure);
            app.channel3SliderLabel.Text = 'channel 3';
            app.channel3Slider = uislider(app.UIFigure);
            app.channel3Slider.Limits = [-10 10];
            app.channel3Slider.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.channel4SliderLabel = uilabel(app.UIFigure);
            app.channel4SliderLabel.Text = 'channel 4';
            app.channel4Slider = uislider(app.UIFigure);
            app.channel4Slider.Limits = [-10 10];
            app.channel4Slider.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.displayCheckBox_1 = uicheckbox(app.UIFigure);
            app.displayCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.displayCheckBox_1.Text = 'display';
            app.displayCheckBox_2 = uicheckbox(app.UIFigure);
            app.displayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.displayCheckBox_2.Text = 'display';
            app.displayCheckBox_3 = uicheckbox(app.UIFigure);
            app.displayCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.displayCheckBox_3.Text = 'display';
            app.displayCheckBox_4 = uicheckbox(app.UIFigure);
            app.displayCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.displayCheckBox_4.Text = 'display';
            app.OFFSETVLabel = uilabel(app.UIFigure);
            app.OFFSETVLabel.Text = 'OFFSET (V)';
            app.VOLTAGEKnobLabel = uilabel(app.UIFigure);
            app.VOLTAGEKnobLabel.Text = 'VOLTAGE';
            app.VOLTAGEKnob = uiknob(app.UIFigure, 'discrete');
            app.VOLTAGEKnob.Items = {'0.01 V', '0.1 V', '1 V', '10 V'};
            app.VOLTAGEKnob.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.VOLTAGEKnob.Value = '1 V';
            app.TIMEKnobLabel = uilabel(app.UIFigure);
            app.TIMEKnobLabel.Text = 'TIME';
            app.TIMEKnob = uiknob(app.UIFigure, 'discrete');
            app.TIMEKnob.Items = {'0.1 sec', '1 sec', '10 sec', '100 sec'};
            app.TIMEKnob.ValueChangedFcn = createCallbackFcn(app, @VOLTAGEKnobValueChanged, true);
            app.TIMEKnob.Value = '1 sec';
            app.daqboardDropDownLabel = uilabel(app.UIFigure);
            app.daqboardDropDownLabel.Text = 'daq board';
            app.daqboardDropDown = uidropdown(app.UIFigure);
            app.daqboardDropDown.ValueChangedFcn = createCallbackFcn(app, @daqboardDropDownValueChanged, true);
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end